classdef IPPR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ControlsPanel              matlab.ui.container.Panel
        RunBatchTestButton         matlab.ui.control.Button
        ConfidenceLabel            matlab.ui.control.Label
        DebugCheckBox              matlab.ui.control.CheckBox
        StatusLabel                matlab.ui.control.Label
        TypeEditField              matlab.ui.control.EditField
        VehicleTypeEditFieldLabel  matlab.ui.control.Label
        StateEditField             matlab.ui.control.EditField
        StateLabel                 matlab.ui.control.Label
        PlateTextEditField         matlab.ui.control.EditField
        PlateTextLabel             matlab.ui.control.Label
        LoadSelectedButton         matlab.ui.control.Button
        ImageDropDown              matlab.ui.control.DropDown
        ImageDropDownLabel         matlab.ui.control.Label
        DatasetDropDown            matlab.ui.control.DropDown
        DatasetDropDownLabel       matlab.ui.control.Label
        ClearButton                matlab.ui.control.Button
        RunButton                  matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        UIAxesPlate                matlab.ui.control.UIAxes
        UIAxesOriginal             matlab.ui.control.UIAxes
    end

    properties (Access = private)
        cfg
        currentImage
        currentImagePath
        
        carDir = "D:\APU DEGREE CS IN AI\DEGREE LEVEL 3\IMAGE PROCESSING, COMPUTER VISION, AND PATTERN RECOGNITION L-3\ASSIGNMENT\IPPR_ASSIGNMENT\DATASETS\CAR";
        militaryDir = "D:\APU DEGREE CS IN AI\DEGREE LEVEL 3\IMAGE PROCESSING, COMPUTER VISION, AND PATTERN RECOGNITION L-3\ASSIGNMENT\IPPR_ASSIGNMENT\DATASETS\MILITARY";
        busDir = "D:\APU DEGREE CS IN AI\DEGREE LEVEL 3\IMAGE PROCESSING, COMPUTER VISION, AND PATTERN RECOGNITION L-3\ASSIGNMENT\IPPR_ASSIGNMENT\DATASETS\BUS";
        lorryDir = "D:\APU DEGREE CS IN AI\DEGREE LEVEL 3\IMAGE PROCESSING, COMPUTER VISION, AND PATTERN RECOGNITION L-3\ASSIGNMENT\IPPR_ASSIGNMENT\DATASETS\LORRY";
        pickupDir = "D:\APU DEGREE CS IN AI\DEGREE LEVEL 3\IMAGE PROCESSING, COMPUTER VISION, AND PATTERN RECOGNITION L-3\ASSIGNMENT\IPPR_ASSIGNMENT\DATASETS\PICKUP";
        vanDir = "D:\APU DEGREE CS IN AI\DEGREE LEVEL 3\IMAGE PROCESSING, COMPUTER VISION, AND PATTERN RECOGNITION L-3\ASSIGNMENT\IPPR_ASSIGNMENT\DATASETS\VAN";
    end
    
    methods (Access = private)
        
        function refreshImageDropdown(app)
            ds = upper(string(app.DatasetDropDown.Value));
            switch ds
                case "CAR"
                    folder = app.carDir;
             case "MILITARY"
                    folder = app.militaryDir;
            case "BUS"
                    folder = app.busDir;
            case "LORRY"
                    folder = app.lorryDir;
            case "PICKUP"
                    folder = app.pickupDir;
            case "VAN"
                    folder = app.vanDir;
            otherwise
                    folder = app.carDir;
            end

            exts = ["*.jpg","*.jpeg","*.png","*.bmp"];
            files = [];
            for e = exts
                files = [files; dir(fullfile(folder, e))];
            end

            if isempty(files)
                app.ImageDropDown.Items = {};
                app.StatusLabel.Text = "No images found in dataset folder.";
            return;
            end

            names = string({files.name});
            app.ImageDropDown.Items = cellstr(names);
            app.ImageDropDown.Value = app.ImageDropDown.Items{1};            
        end
        
        function out = ternary(app, cond, a, b)
            if cond
                out = a;
            else, 
                out = b;
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cfg = struct();

            app.cfg.mode = "CAR";

            app.cfg.ocrCharset = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ0123456789';
            app.cfg.minTextLen = 4;

            app.cfg.plateAspectMin = 1.8;
            app.cfg.plateAspectMax = 12.0;
            app.cfg.minPlateWidthFrac = 0.05;
            app.cfg.minPlateHeightFrac = 0.015;
            app.cfg.maxPlateHeightFrac = 0.45;

            app.cfg.plateMaxAreaFrac = 0.12;
            app.cfg.plateMinAspect = 2.2;
            app.cfg.plateMaxAspect = 6.5;
            app.cfg.plateMinAreaFrac = 0.0005;

            app.cfg.mserMinArea = 80;
            app.cfg.mserMaxArea = 12000;
            app.cfg.mserThresholdDelta = 2;

            app.cfg.minCharsInGroup = 2;
            app.cfg.maxCharsInGroup = 14;
            app.cfg.maxYStdFrac = 2.0;
            app.cfg.maxHVarFrac = 2.0;
            app.cfg.maxXGapFrac = 6.0;

            app.cfg.roiTopFrac_CAR = 0.30;
            app.cfg.roiTopFrac_MIL = 0.20;

            app.cfg.debugMaxCandidates = 25;

            app.cfg.enableFallback = true;

            app.StatusLabel.Text = "Ready";

            app.cfg.stateMap = containers.Map( ...
                {'A','B','C','D','J','K','M','N','P','R','T','W','V','Q','S','L','KV'}, ...
                {'Perak','Selangor','Pahang','Kelantan','Johor','Kedah','Melaka','Negeri Sembilan', ...
                'Pulau Pinang','Perlis','Terengganu','Kuala Lumpur','Kuala Lumpur','Sarawak','Sabah','Labuan','Langkawi'} );

            if isprop(app, "DatasetDropDown")
                app.DatasetDropDown.Items = {'CAR','MILITARY','BUS','LORRY','PICKUP','VAN'};
                app.DatasetDropDown.Value = 'CAR';
            end

            if isprop(app, "StatusLabel")
                app.StatusLabel.Text = "Ready";
            end

            if exist('refreshImageDropdown', 'file') == 0
            else
            end

            try
                refreshImageDropdown(app);
            catch
            end
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            refreshImageDropdown(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [f,p] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp','Image Files'}, 'Select an image');
            if isequal(f,0)
                app.StatusLabel.Text = "Load cancelled.";
                return;
            end

            path = fullfile(p,f);
            I = imread(path);

            app.currentImage = I;
            app.currentImagePath = string(path);

            imshow(I, 'Parent', app.UIAxesOriginal);
            title(app.UIAxesOriginal, "Original Image (Detection)");

            cla(app.UIAxesPlate);
            app.PlateTextEditField.Value = "";
            app.StateEditField.Value = "";
            app.TypeEditField.Value = "";

            app.StatusLabel.Text = "Image loaded: " + string(f);
        end

        % Button pushed function: LoadSelectedButton
        function LoadSelectedButtonPushed(app, event)
            if isempty(app.ImageDropDown.Items)
                app.StatusLabel.Text = "No dataset images available.";
                return;
            end

            ds = upper(string(app.DatasetDropDown.Value));

            switch ds
                case "CAR"
                    folder = app.carDir;
                case "MILITARY"
                    folder = app.militaryDir;
                case "BUS"
                    folder = app.busDir;
                case "LORRY"
                    folder = app.lorryDir;
                case "PICKUP"
                    folder = app.pickupDir;
                case "VAN"
                    folder = app.vanDir;
                otherwise
                    folder = app.carDir;
            end

            fname = string(app.ImageDropDown.Value);
            path = fullfile(folder, fname);

            I = imread(path);
            app.currentImage = I;
            app.currentImagePath = string(path);

            imshow(I, 'Parent', app.UIAxesOriginal);
            title(app.UIAxesOriginal, "Original Image (Detection)");

            cla(app.UIAxesPlate);
            app.PlateTextEditField.Value = "";
            app.StateEditField.Value = "";
            app.TypeEditField.Value = "";

            app.StatusLabel.Text = "Loaded dataset image: " + fname;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isempty(app.currentImage)
            app.StatusLabel.Text = "Please load an image first.";
            return;
        end

        app.StatusLabel.Text = "Running LPR + SIS...";
        app.ConfidenceLabel.Text = "Confidence: -";
        drawnow;

        ds = upper(string(app.DatasetDropDown.Value));

        if ds == "MILITARY"
            app.cfg.mode = "MILITARY";
        else
            app.cfg.mode = "CAR";
        end

        app.cfg.vehicleType = ds;

        I = app.currentImage;

        [bbox, plateCrop, candBBoxes, candScores, detConf] = detectPlateMSER(I, app.cfg);

        imshow(I, 'Parent', app.UIAxesOriginal);
        hold(app.UIAxesOriginal, 'on');

        debugOn = false;
        if isprop(app, "DebugCheckBox")
            debugOn = logical(app.DebugCheckBox.Value);
        end

        if debugOn && ~isempty(candBBoxes)
            [sSorted, idx] = sort(candScores, 'descend');
            K = min(numel(idx), app.cfg.debugMaxCandidates);
            idx = idx(1:K);
            sK = sSorted(1:K);

            sMin = min(sK); sMax = max(sK);
            denom = (sMax - sMin);
            if denom < eps, denom = 1; end

            for i = 1:K
                bb = candBBoxes(idx(i), :);
                t = (sK(i) - sMin) / denom;
                col = [1, t, 0];
                rectangle(app.UIAxesOriginal, 'Position', bb, 'EdgeColor', col, 'LineWidth', 1);
            end
        end

        if isempty(bbox) || isempty(plateCrop)
            hold(app.UIAxesOriginal, 'off');

            cla(app.UIAxesPlate);
            app.PlateTextEditField.Value = "";
            app.StateEditField.Value = "Unknown";
            app.TypeEditField.Value = "";
            app.StatusLabel.Text = "No plate detected.";
            app.ConfidenceLabel.Text = "Confidence: 0.0%";
            return;
        end

        rectangle(app.UIAxesOriginal, 'Position', bbox, 'EdgeColor', 'g', 'LineWidth', 3);
        hold(app.UIAxesOriginal, 'off');

        imshow(plateCrop, 'Parent', app.UIAxesPlate);

        [plateText, conf] = recognizePlateText(plateCrop, app.cfg);

        if isempty(conf)
            ocrConf = 0;
        else
            ocrConf = double(conf);
            if ocrConf > 1
                ocrConf = ocrConf / 100;
            end
            ocrConf = max(0, min(1, ocrConf));
        end

        if isempty(plateText)
            plateText = "";
        else
            plateText = string(plateText);
            plateText = upper(plateText);
            plateText = regexprep(plateText, '\s+', '');
        end

        mil = isMilitaryPlate(plateText);

        state = inferStateFromPlate(plateText, app.cfg);

        detConf = max(0, min(1, double(detConf)));
        overallConf = 0.5*detConf + 0.5*ocrConf;

        app.PlateTextEditField.Value = char(plateText);
        app.StateEditField.Value     = char(state);

        if mil
            app.TypeEditField.Value = "Military";
        else
            app.TypeEditField.Value = "Civilian";
        end

        app.ConfidenceLabel.Text = sprintf("Confidence: %.1f%% (Det %.0f%% | OCR %.0f%%)", ...
            overallConf*100, detConf*100, ocrConf*100);

        if strlength(plateText) < app.cfg.minTextLen
            app.StatusLabel.Text = "Done (OCR weak/empty).";
        else
            app.StatusLabel.Text = "Done.";
        end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.currentImage = [];
            app.currentImagePath = "";

            cla(app.UIAxesOriginal);
            cla(app.UIAxesPlate);

            app.PlateTextEditField.Value = "";
            app.StateEditField.Value = "";
            app.TypeEditField.Value = "";

            app.StatusLabel.Text = "Cleared.";
        end

        % Button pushed function: RunBatchTestButton
        function RunBatchTestButtonPushed(app, event)
            % Try to default to your dataset folder if you have it
            startFolder = pwd;
            if isfield(app, 'cfg') && isfield(app.cfg, 'datasetRoot') && isfield(app, 'DatasetDropDown')
                startFolder = fullfile(app.cfg.datasetRoot, app.DatasetDropDown.Value);
            end

            folder = uigetdir(startFolder, 'Select folder containing test images');
            if isequal(folder, 0)
                return;
            end

            [f, p] = uiputfile('*.csv', 'Save batch results as...', ...
                fullfile(folder, ['batch_results_' datestr(now,'yyyymmdd_HHMMSS') '.csv']));
            if isequal(f, 0)
                return;
            end
            outCsvPath = fullfile(p, f);

            % Optional: show status in your label if you have one
            if isprop(app, 'StatusLabel')
                app.StatusLabel.Text = 'Running batch test...';
                drawnow;
            end

            resultsTbl = runBatchTest(folder, app.cfg, outCsvPath); %#ok<NASGU>

            if isprop(app, 'StatusLabel')
                app.StatusLabel.Text = ['Batch done! Saved: ' outCsvPath];
            end

            uialert(app.UIFigure, sprintf('Batch test complete!\n\nSaved CSV:\n%s', outCsvPath), 'Done');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.UIAxesOriginal = uiaxes(app.ControlsPanel);
            title(app.UIAxesOriginal, 'Original Image (Detection)')
            xlabel(app.UIAxesOriginal, 'X')
            ylabel(app.UIAxesOriginal, 'Y')
            zlabel(app.UIAxesOriginal, 'Z')
            app.UIAxesPlate = uiaxes(app.ControlsPanel);
            title(app.UIAxesPlate, 'Detected Plate (Close-up)')
            xlabel(app.UIAxesPlate, 'X')
            ylabel(app.UIAxesPlate, 'Y')
            zlabel(app.UIAxesPlate, 'Z')
            app.LoadButton = uibutton(app.ControlsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = {'Load'; 'image'};
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = {'Run'; 'LPR + SIS'};
            app.ClearButton = uibutton(app.ControlsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DatasetDropDownLabel = uilabel(app.ControlsPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.ControlsPanel);
            app.DatasetDropDown.Items = {'CAR', 'MILITARY', 'BUS', 'LORRY', 'VAN', 'PICKUP'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 'CAR';
            app.ImageDropDownLabel = uilabel(app.ControlsPanel);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.ControlsPanel);
            app.LoadSelectedButton = uibutton(app.ControlsPanel, 'push');
            app.LoadSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectedButtonPushed, true);
            app.LoadSelectedButton.Text = {'Load'; 'Selected'};
            app.PlateTextLabel = uilabel(app.ControlsPanel);
            app.PlateTextLabel.Text = 'Plate Text:';
            app.PlateTextEditField = uieditfield(app.ControlsPanel, 'text');
            app.StateLabel = uilabel(app.ControlsPanel);
            app.StateLabel.Text = 'State:';
            app.StateEditField = uieditfield(app.ControlsPanel, 'text');
            app.VehicleTypeEditFieldLabel = uilabel(app.ControlsPanel);
            app.VehicleTypeEditFieldLabel.Text = 'Vehicle Type:';
            app.TypeEditField = uieditfield(app.ControlsPanel, 'text');
            app.StatusLabel = uilabel(app.ControlsPanel);
            app.StatusLabel.Text = 'Ready';
            app.DebugCheckBox = uicheckbox(app.ControlsPanel);
            app.DebugCheckBox.Text = 'Debug';
            app.ConfidenceLabel = uilabel(app.ControlsPanel);
            app.ConfidenceLabel.Text = 'Confidence: ';
            app.RunBatchTestButton = uibutton(app.ControlsPanel, 'push');
            app.RunBatchTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchTestButtonPushed, true);
            app.RunBatchTestButton.Text = {'Run'; 'Batch Test'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPPR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end